classdef tip_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SelectVanishingPointButton  matlab.ui.control.StateButton
        UploadnewImageButton        matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        Tree                        matlab.ui.container.CheckBoxTree
        Node                        matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        PushTool3                   matlab.ui.container.toolbar.PushTool
        PushTool2                   matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        VanishingPoint double = []; % To store the coordinates of the vanishing point
        BackgroundPolygon = {} % Way of storing this: [upperLeft, upperRight, lowerRight, lowerLeft, upperLeft]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadnewImageButton
        function UploadnewImageButtonPushed(app, event)
            % Open file selection dialog box for image files
            [file, path] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an Image');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % Create the full file path and display the image on UIAxes
                fullFilePath = fullfile(path, file);
                % Clear any existing plots in UIAxes
                cla(app.UIAxes);
                % Display image in UIAxes

                img = imshow(fullFilePath, 'Parent', app.UIAxes);
                disp(['User selected ', fullFilePath]);
                set(img, 'ButtonDownFcn', @(src, event)UIAxesButtonDown(app, event));
            end
        end

        % Value changed function: SelectVanishingPointButton
        function SelectVanishingPointButtonValueChanged(app, event)
            value = app.SelectVanishingPointButton.Value;
            disp(event)
        end

        % Callback function
        function ImageClicked(app, event)
           disp(event)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Check if the Select Vanishing Point mode is enabled
            % Get the coordinates of the click relative to the UIAxes
            disp("UI Axes Button down triggered")
            %coords = app.UIAxes.CurrentPoint;
            %x = coords(1,1);
            %y = coords(1,2);
            
            % Store the vanishing point coordinates
            %app.VanishingPoint = [x, y];
        
            % Optionally print the vanishing point coordinates to the MATLAB Command Window
            %disp(['Vanishing point selected at: [', num2str(x), ', ', num2str(y), ']']);
        
            % Visualize the vanishing point on the UIAxes
            %hold(app.UIAxes, 'on');
            %scatter(app.UIAxes, x, y, 100, 'r', 'filled'); % You can adjust the size and color
            %hold(app.UIAxes, 'off');

            % draw polygon
            roi = drawrectangle('Color','b', 'Parent', app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {441, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UploadnewImageButton = uibutton(app.LeftPanel, 'push');
            app.UploadnewImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadnewImageButtonPushed, true);
            app.UploadnewImageButton.Text = 'Upload new Image';
            app.SelectVanishingPointButton = uibutton(app.LeftPanel, 'state');
            app.SelectVanishingPointButton.ValueChangedFcn = createCallbackFcn(app, @SelectVanishingPointButtonValueChanged, true);
            app.SelectVanishingPointButton.Text = 'Select Vanishing Point';
            app.RightPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.RightPanel, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Tree);
            app.Node3.Text = 'Node3';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tip_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end